classdef BOARD_FTD_PACC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadButton_2                  matlab.ui.control.Button
        InterChannelPanel             matlab.ui.container.Panel
        wdEditField                   matlab.ui.control.NumericEditField
        wdEditFieldLabel              matlab.ui.control.Label
        Resample_inter_Ch             matlab.ui.control.NumericEditField
        FqHzLabel                     matlab.ui.control.Label
        Ch_AEditField                 matlab.ui.control.NumericEditField
        Ch_AEditFieldLabel            matlab.ui.control.Label
        Ch_PEditField                 matlab.ui.control.NumericEditField
        Ch_PEditFieldLabel            matlab.ui.control.Label
        FmaxEditField                 matlab.ui.control.NumericEditField
        FmaxEditFieldLabel            matlab.ui.control.Label
        FminEditField                 matlab.ui.control.NumericEditField
        FminEditFieldLabel            matlab.ui.control.Label
        PACButton_2                   matlab.ui.control.Button
        PPCButton                     matlab.ui.control.Button
        ArtifactPanel                 matlab.ui.container.Panel
        ApplyButton                   matlab.ui.control.Button
        A2posfEditField               matlab.ui.control.NumericEditField
        A2posfEditFieldLabel          matlab.ui.control.Label
        A2posiEditField               matlab.ui.control.NumericEditField
        A2posiEditFieldLabel          matlab.ui.control.Label
        A1LEditField                  matlab.ui.control.NumericEditField
        A1LEditFieldLabel             matlab.ui.control.Label
        RemoveDropDown                matlab.ui.control.DropDown
        RemoveDropDownLabel           matlab.ui.control.Label
        ViewButton                    matlab.ui.control.Button
        PACPanel                      matlab.ui.container.Panel
        PACPselButton                 matlab.ui.control.Button
        PACAverageButton              matlab.ui.control.Button
        A2EditField                   matlab.ui.control.NumericEditField
        A2EditFieldLabel              matlab.ui.control.Label
        A1EditField                   matlab.ui.control.NumericEditField
        A1EditFieldLabel              matlab.ui.control.Label
        P2EditField                   matlab.ui.control.NumericEditField
        P2EditFieldLabel              matlab.ui.control.Label
        P1EditField                   matlab.ui.control.NumericEditField
        P1EditFieldLabel              matlab.ui.control.Label
        PACButton                     matlab.ui.control.Button
        ResamplePAC                   matlab.ui.control.NumericEditField
        SampleFqHzLabel_3             matlab.ui.control.Label
        MIButton                      matlab.ui.control.Button
        AstepEditField                matlab.ui.control.NumericEditField
        AstepEditFieldLabel           matlab.ui.control.Label
        FqA2EditField                 matlab.ui.control.NumericEditField
        FqA2EditFieldLabel            matlab.ui.control.Label
        FqA1EditField                 matlab.ui.control.NumericEditField
        FqA1EditFieldLabel            matlab.ui.control.Label
        PstepEditField                matlab.ui.control.NumericEditField
        PstepEditFieldLabel           matlab.ui.control.Label
        FqP2EditField                 matlab.ui.control.NumericEditField
        FqP2EditFieldLabel            matlab.ui.control.Label
        FqP1EditField                 matlab.ui.control.NumericEditField
        FqP1EditFieldLabel            matlab.ui.control.Label
        TimeFrequencyPanel            matlab.ui.container.Panel
        HistButton                    matlab.ui.control.Button
        TFAverageButton               matlab.ui.control.Button
        TFButton                      matlab.ui.control.Button
        LogCheckBox                   matlab.ui.control.CheckBox
        ResampleTF                    matlab.ui.control.NumericEditField
        SampleFqHzLabel_2             matlab.ui.control.Label
        NormCheckBox                  matlab.ui.control.CheckBox
        CyclesEditField               matlab.ui.control.NumericEditField
        CyclesEditFieldLabel          matlab.ui.control.Label
        F2                            matlab.ui.control.NumericEditField
        F2EditField_2Label            matlab.ui.control.Label
        F1                            matlab.ui.control.NumericEditField
        F1EditField_2Label            matlab.ui.control.Label
        MeasuresPanel                 matlab.ui.container.Panel
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        MeasureButton                 matlab.ui.control.Button
        R2t2EditField                 matlab.ui.control.NumericEditField
        R2t2EditFieldLabel            matlab.ui.control.Label
        R2t1EditField                 matlab.ui.control.NumericEditField
        R2t1EditFieldLabel            matlab.ui.control.Label
        R1t2EditField                 matlab.ui.control.NumericEditField
        R1t2EditFieldLabel            matlab.ui.control.Label
        R1t1EditField                 matlab.ui.control.NumericEditField
        R1t1EditFieldLabel            matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        PlotButton                    matlab.ui.control.Button
        FrequencyPanel                matlab.ui.container.Panel
        RelativePowEditField          matlab.ui.control.NumericEditField
        RelativePowEditFieldLabel     matlab.ui.control.Label
        PowEditField                  matlab.ui.control.NumericEditField
        PowEditFieldLabel             matlab.ui.control.Label
        RelativePSDButton             matlab.ui.control.Button
        Fq2EditField                  matlab.ui.control.NumericEditField
        Fq2EditFieldLabel             matlab.ui.control.Label
        Fq1EditField                  matlab.ui.control.NumericEditField
        Fq1EditFieldLabel             matlab.ui.control.Label
        FFTButton                     matlab.ui.control.Button
        PSDButton                     matlab.ui.control.Button
        PSDAverageButton              matlab.ui.control.Button
        FFTAverageButton              matlab.ui.control.Button
        ResampleFr                    matlab.ui.control.NumericEditField
        SampleFqHzLabel               matlab.ui.control.Label
        TimePanel                     matlab.ui.container.Panel
        AverageButton                 matlab.ui.control.Button
        TrialviewButton               matlab.ui.control.Button
        filterButton                  matlab.ui.control.Button
        OrderEditField                matlab.ui.control.NumericEditField
        OrderEditFieldLabel           matlab.ui.control.Label
        FqHighEditField               matlab.ui.control.NumericEditField
        FqHighEditFieldLabel          matlab.ui.control.Label
        FqLowEditField                matlab.ui.control.NumericEditField
        FqLowEditFieldLabel           matlab.ui.control.Label
        RMSButton                     matlab.ui.control.Button
        ERPPlotButton                 matlab.ui.control.Button
        BasicInformationPanel         matlab.ui.container.Panel
        SingleTrialEditField          matlab.ui.control.NumericEditField
        SingleTrialEditFieldLabel     matlab.ui.control.Label
        SampleFqHzEditField           matlab.ui.control.NumericEditField
        SampleFqHzEditFieldLabel      matlab.ui.control.Label
        ResampleButton                matlab.ui.control.Button
        InterStimTimeEditField        matlab.ui.control.NumericEditField
        InterStimTimeEditFieldLabel   matlab.ui.control.Label
        NoStimCheckBox                matlab.ui.control.CheckBox
        LoadTrialsButton              matlab.ui.control.Button
        FinalTimeEditField            matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel       matlab.ui.control.Label
        InitialTimeEditField          matlab.ui.control.NumericEditField
        InitialTimeEditFieldLabel     matlab.ui.control.Label
        SelectedTrialsEditField       matlab.ui.control.EditField
        SelectedTrialsEditFieldLabel  matlab.ui.control.Label
        AllTrialsCheckBox             matlab.ui.control.CheckBox
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        StimNumEditField              matlab.ui.control.NumericEditField
        StimNumEditFieldLabel         matlab.ui.control.Label
        ChannelEditField              matlab.ui.control.NumericEditField
        ChannelEditFieldLabel         matlab.ui.control.Label
        InitialFigureButton           matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        m_Data % Data in columns
        srate % sample rate
        h %header info
        v_Time % time vector
        num_Ch % channel number
        m_Trials1 % matrix of trials in columns
        m_Trials2 % matrix of trials in columns (other channel)
        m_Trials1_w % matrix of trials taking in acount the number of stimuly
        m_Trials1_wt % matrix of trials in time window
        m_Trials1_selected % matrix of selected trials in columns
        m_Trials2_w % matrix of trials taking in acount the number of stimuly
        m_Trials2_wt % matrix of trials in time window
        m_Trials2_selected % matrix of selected trials in columns
       
        v_Timew %time vector for the trial window
        m_Trials1_freq %trials for frequency analisis (without time selection)
        m_Trials1_freq_sel %trials for frequency analisis (selected)(without time selection)
        m_Trials1AVw%averages for measure
        m_Trials2_freq %trials for frequency analisis (without time selection)
        m_Trials2_freq_sel %trials for frequency analisis (selected)(without time selection)
        m_Trials2AVw%averages for measure
        
        
        v_ini %initial vector before stimulus
        single_trial %trial for analisis and visualization
        sel_Ch % Selected channel
        GF %record without stimulation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            str_FullFileName=app.FileNameEditField.Value;
            [app.m_Data,app.srate,app.h,app.v_Time,app.num_Ch] = f_LoadSignal(str_FullFileName);
          
        end

        % Button pushed function: InitialFigureButton
        function InitialFigureButtonPushed(app, event)
            f_Plot_Ch(app.v_Time,app.m_Data,app.h,app.num_Ch);
        end

        % Button pushed function: LoadTrialsButton
        function LoadTrialsButtonPushed(app, event)
            app.sel_Ch = app.ChannelEditField.Value;
            Stim_num = app.StimNumEditField.Value;
            Th = app.ThresholdEditField.Value;
            ti = app.InitialTimeEditField.Value;
            tf = app.FinalTimeEditField.Value;
            app.GF = app.NoStimCheckBox.Value;
            tis = app.InterStimTimeEditField.Value;
            all_trials = app.AllTrialsCheckBox.Value;
            app.single_trial =app.SingleTrialEditField.Value;
           
           
            v_Trials_str=app.SelectedTrialsEditField.Value;
            v_Trials=str2double(strsplit(v_Trials_str,','));


            if app.GF==1
                [app.m_Trials1_w,app.m_Trials1_wt,app.v_Timew,app.m_Trials1_freq] = f_Load_GF(app.m_Data,app.v_Time,ti,tf,app.srate,app.h,app.sel_Ch);
            else
                [app.m_Trials1,app.v_ini]=f_Cols_Trials(app.m_Data,app.sel_Ch,Th,Stim_num,ti,app.num_Ch,app.srate);
                [app.m_Trials1_w,t_end,app.m_Trials1_freq] = f_Assemble_Trials(app.m_Trials1,Stim_num,tis,app.srate);
                [app.m_Trials1_wt,app.v_Timew] = f_Assemble_Time(app.m_Trials1_w,app.v_ini,ti,tf,t_end,app.srate);
            end

            [app.m_Trials1_selected,app.m_Trials1_freq_sel] = f_Select_Trials(app.m_Trials1_wt,v_Trials,app.v_Timew,all_trials,app.m_Trials1_freq,app.GF,app.h,app.sel_Ch);
            
                    %f_plot_single(1,app.h,app.single_trial,app.sel_Ch);
            
        end

        % Button pushed function: FFTAverageButton
        function FFTAverageButtonPushed(app, event)
            app.single_trial =app.SingleTrialEditField.Value;
           f_target=app.ResampleFr.Value; 
            srt =round(app.srate/f_target);
          
            f_FFT_Average(app.m_Trials1_selected,app.srate,srt,app.GF,app.h,app.sel_Ch);
        end

        % Button pushed function: PSDAverageButton
        function PSDAverageButtonPushed(app, event)
            app.single_trial =app.SingleTrialEditField.Value;
            f_target=app.ResampleFr.Value; 
            srt =round(app.srate/f_target);
         
            f_PSD_Average(app.m_Trials1_selected,srt,app.srate,app.GF,app.h,app.sel_Ch);
        end

        % Button pushed function: PSDButton
        function PSDButtonPushed(app, event)
            app.single_trial =app.SingleTrialEditField.Value;
            f_target=app.ResampleFr.Value; 
            srt =round(app.srate/f_target);
            f_PSD(app.m_Trials1_selected,srt,app.srate,app.single_trial,app.h,app.sel_Ch);
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
           app.single_trial =app.SingleTrialEditField.Value;
            f_target=app.ResampleFr.Value; 
            srt =round(app.srate/f_target);
            f_FFT(app.m_Trials1_selected,app.srate,srt,app.single_trial,app.h,app.sel_Ch);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
              f_Average(app.m_Trials1_selected,app.v_Timew,app.h,app.sel_Ch);
        end

        % Button pushed function: ERPPlotButton
        function ERPPlotButtonPushed(app, event)
                f_Butterfly(app.v_Timew,app.m_Trials1_selected,app.h,app.sel_Ch);
        end

        % Button pushed function: RMSButton
        function RMSButtonPushed(app, event)
            
            f_RMS_zscore(app.m_Trials1_selected,app.srate,app.v_Timew,app.GF,app.h,app.sel_Ch);
        end

        % Button pushed function: RelativePSDButton
        function RelativePSDButtonPushed(app, event)
           
      
            f_target=app.ResampleFr.Value; 
            srt =round(app.srate/f_target);
            
            Fq1=app.Fq1EditField.Value;
            Fq2=app.Fq2EditField.Value;
            [pow,powr,pr,p]=f_PSD_Relative(app.m_Trials1_selected,srt,app.srate,Fq1,Fq2,app.GF);
            app.PowEditField.Value=pow;
            app.RelativePowEditField.Value=powr;
          
            disp(p');
            disp(pr');
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x=app.xEditField.Value;
            if strcmp(app.DropDown.Value,'Full AV')
                option=0;
            else
                option=1;
            end
            Stim_num = app.StimNumEditField.Value;
        
            
            [app.m_Trials1AVw]=f_measure_plot(app.m_Trials1_selected,Stim_num,app.v_Timew,option,app.GF,x,app.h,app.sel_Ch); 
            
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            r1t1=app.R1t1EditField.Value;
            r1t2=app.R1t2EditField.Value;
            r2t1=app.R2t1EditField.Value;
            r2t2=app.R2t2EditField.Value;
            
            
            if app.GF==1
                S1=0;
                S2=0;
            else         
                 if strcmp(app.DropDown.Value,'Full')
                    option=0;
                else
                    option=1;
                end
                Stim_num = app.StimNumEditField.Value;
                
                [S1,S2] = f_Measure_Slope(app.m_Trials1AVw,r1t1,r1t2,r2t1,r2t2,app.srate,app.v_Timew,Stim_num);
                
                disp(S1);
                disp(S2);
            end
            %app.Slope1EditField.Value=S1;
            %app.Slope2EditField.Value=S2;
        end

        % Button pushed function: TFButton
        function TFButtonPushed(app, event)
        app.single_trial =app.SingleTrialEditField.Value;
        f_target=app.ResampleFr.Value; 
        srt =round(app.srate/f_target);
            
        ps_MinFreqHz = app.F1.Value;
        ps_MaxFreqHz = app.F2.Value;
        ps_StDevCycles=app.CyclesEditField.Value;
        norm=app.NormCheckBox.Value;
        log=app.LogCheckBox.Value;
       

        f_tf(app.m_Trials1_selected,ps_MinFreqHz,ps_MaxFreqHz,srt,app.srate,app.v_Timew,ps_StDevCycles,norm,log,app.single_trial,app.GF);
        end

        % Button pushed function: TFAverageButton
        function TFAverageButtonPushed(app, event)
        ps_MinFreqHz = app.F1.Value;
        ps_MaxFreqHz = app.F2.Value;
        ps_StDevCycles=app.CyclesEditField.Value;
        norm=app.NormCheckBox.Value;
        log=app.LogCheckBox.Value;
        f_target=app.ResampleFr.Value; 
        srt =round(app.srate/f_target);

        f_tf_average(app.m_Trials1_selected,ps_MinFreqHz,ps_MaxFreqHz,srt,app.srate,app.v_Timew,ps_StDevCycles,norm,log,app.h,app.sel_Ch);
        end

        % Button pushed function: MIButton
        function MIButtonPushed(app, event)
            app.single_trial =app.SingleTrialEditField.Value;
            p_ini=app.FqP1EditField.Value;
            p_fin=app.FqP2EditField.Value;
            pstep=app.PstepEditField.Value;
            a_ini=app.FqA1EditField.Value;
            a_fin=app.FqA2EditField.Value; 
            astep=app.AstepEditField.Value;
            f_target=app.ResampleFr.Value; 
            srt =round(app.srate/f_target);
            f_PAC_sing( app.m_Trials1_selected, app.srate, p_ini, p_fin, pstep, a_ini, a_fin, astep, srt,app.single_trial,app.GF );

        end

        % Button pushed function: PACButton
        function PACButtonPushed(app, event)
            app.single_trial =app.SingleTrialEditField.Value;
            P1=app.P1EditField.Value;
            P2=app.P2EditField.Value;
            A1=app.A1EditField.Value;
            A2=app.A2EditField.Value;
            f_target=app.ResamplePAC.Value; 
            srt =round(app.srate/f_target);
            f_Phase_PAC(P1,P2,A1,A2,app.m_Trials1_selected,app.srate,srt,app.single_trial,app.GF,app.v_Timew);
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            app.single_trial=app.SingleTrialEditField.Value;
            Flow=app.FqLowEditField.Value; 
            Fhigh=app.FqHighEditField.Value;
            Order=app.OrderEditField.Value;
             app.single_trial=app.SingleTrialEditField.Value;
             
             
            f_Filter_order_manual(Flow,Fhigh,Order,app.m_Data(:,app.sel_Ch),app.srate,app.v_Time,app.m_Trials1_selected,app.v_Timew,app.single_trial,app.h,app.sel_Ch);
            %f_Filter_auto(Flow,Fhigh,app.m_Data(:,app.sel_Ch),app.srate,app.v_Time,app.m_Trials1_selected,app.v_Timew,app.single_trial,app.h,app.sel_Ch);


        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            f_view_art(app.m_Trials1_w,app.h,app.sel_Ch);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            
            if strcmp(app.RemoveDropDown.Value,'Off')
                option=0;
            elseif strcmp(app.RemoveDropDown.Value,'On first')
                option=1;
            else
                option=2;
            end
            [app.m_Trials1] = f_Remove_art(app.m_Trials1,option,app.A1LEditField.Value,app.A2posfEditField.Value,app.A2posiEditField.Value);
            
           
            Stim_num = app.StimNumEditField.Value;
            Th = app.ThresholdEditField.Value;
            ti = app.InitialTimeEditField.Value;
            tf = app.FinalTimeEditField.Value;
            tis = app.InterStimTimeEditField.Value;
            all_trials = app.AllTrialsCheckBox.Value;
           
           
            v_Trials_str=app.SelectedTrialsEditField.Value;
            v_Trials=str2double(strsplit(v_Trials_str,','));
            
          
                [app.m_Trials1_w,t_end,app.m_Trials1_freq] = f_Assemble_Trials(app.m_Trials1,Stim_num,tis,app.srate);
                [app.m_Trials1_wt,app.v_Timew] = f_Assemble_Time(app.m_Trials1_w,app.v_ini,ti,tf,t_end,app.srate);
         
            [app.m_Trials1_selected,app.m_Trials1_freq_sel] = f_Select_Trials(app.m_Trials1_wt,v_Trials,app.v_Timew,all_trials,app.m_Trials1_freq,app.GF,app.h,app.sel_Ch);
            
     
        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            f_target=app.SampleFqHzEditField.Value;
            [app.m_Trials1_selected,app.m_Trials1_freq_sel] = f_Resample_mat(app.m_Trials1_selected,app.m_Trials1_freq_sel,app.v_Timew,f_target,app.srate);
          
        end

        % Button pushed function: TrialviewButton
        function TrialviewButtonPushed(app, event)
            app.single_trial=app.SingleTrialEditField.Value;
            f_plot_single(app.m_Trials1_selected,app.h,app.single_trial,app.sel_Ch);
        end

        % Button pushed function: PACAverageButton
        function PACAverageButtonPushed(app, event)
            app.single_trial =app.SingleTrialEditField.Value;
            P1=app.P1EditField.Value;
            P2=app.P2EditField.Value;
            A1=app.A1EditField.Value;
            A2=app.A2EditField.Value;
            f_target=app.ResamplePAC.Value; 
            srt =round(app.srate/f_target);
            f_Phase_PAC_Average(P1,P2,A1,A2,app.m_Trials1_selected,app.srate,srt,app.single_trial,app.GF,app.v_Timew);


        end

        % Button pushed function: PACPselButton
        function PACPselButtonPushed(app, event)
             app.single_trial =app.SingleTrialEditField.Value;
            P1=app.P1EditField.Value;
            P2=app.P2EditField.Value;
            A1=app.A1EditField.Value;
            A2=app.A2EditField.Value;
            f_target=app.ResamplePAC.Value; 
            srt =round(app.srate/f_target);
            f_Phase_PAC_Pdetect(P1,P2,A1,A2,app.m_Trials1_selected,app.srate,srt);
          
  
        end

        % Button pushed function: PPCButton
        function PPCButtonPushed(app, event)


            f_target=app.Resample_inter_Ch.Value; 
            srt =round(app.srate/f_target);
            f_min=app.FminEditField.Value;
            f_max=app.FmaxEditField.Value;
            wd=app.wdEditField.Value;
            
             f_phase_coherence( app.m_Trials1_selected, app.m_Trials2_selected,app.srate, srt, f_min, f_max, wd,app.v_Timew )
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            Ch1 = app.Ch_PEditField.Value;
            Ch2 = app.Ch_AEditField.Value;
            
            
            Stim_num = app.StimNumEditField.Value;
            Th = app.ThresholdEditField.Value;
            ti = app.InitialTimeEditField.Value;
            tf = app.FinalTimeEditField.Value;
            app.GF = app.NoStimCheckBox.Value;
            tis = app.InterStimTimeEditField.Value;
            all_trials = app.AllTrialsCheckBox.Value;
            app.single_trial =app.SingleTrialEditField.Value;
           
           
            v_Trials_str=app.SelectedTrialsEditField.Value;
            v_Trials=str2double(strsplit(v_Trials_str,','));


            if app.GF==1
                [app.m_Trials1_w,app.m_Trials1_wt,app.v_Timew,app.m_Trials1_freq] = f_Load_GF(app.m_Data,app.v_Time,ti,tf,app.srate,app.h,Ch1);
                [app.m_Trials2_w,app.m_Trials2_wt,app.v_Timew,app.m_Trials2_freq] = f_Load_GF(app.m_Data,app.v_Time,ti,tf,app.srate,app.h,Ch2);
            else
                [app.m_Trials1,app.v_ini]=f_Cols_Trials(app.m_Data,Ch1,Th,Stim_num,ti,app.num_Ch,app.srate);
                [app.m_Trials1_w,t_end,app.m_Trials1_freq] = f_Assemble_Trials(app.m_Trials1,Stim_num,tis,app.srate);
                [app.m_Trials1_wt,app.v_Timew] = f_Assemble_Time(app.m_Trials1_w,app.v_ini,ti,tf,t_end,app.srate);
                
                [app.m_Trials2,app.v_ini]=f_Cols_Trials(app.m_Data,Ch2,Th,Stim_num,ti,app.num_Ch,app.srate);
                [app.m_Trials2_w,t_end,app.m_Trials2_freq] = f_Assemble_Trials(app.m_Trials2,Stim_num,tis,app.srate);
                [app.m_Trials2_wt,app.v_Timew] = f_Assemble_Time(app.m_Trials2_w,app.v_ini,ti,tf,t_end,app.srate);
            end

            [app.m_Trials1_selected,app.m_Trials1_freq_sel] = f_Select_Trials(app.m_Trials1_wt,v_Trials,app.v_Timew,all_trials,app.m_Trials1_freq,app.GF,app.h,Ch1);
            [app.m_Trials2_selected,app.m_Trials2_freq_sel] = f_Select_Trials(app.m_Trials2_wt,v_Trials,app.v_Timew,all_trials,app.m_Trials2_freq,app.GF,app.h,Ch2);


            if strcmp(app.RemoveDropDown.Value,'Off')
                option=0;
            elseif strcmp(app.RemoveDropDown.Value,'On first')
                option=1;
            else
                option=2;
            end
            [app.m_Trials1] = f_Remove_art(app.m_Trials1,option,app.A1LEditField.Value,app.A2posfEditField.Value,app.A2posiEditField.Value);
            [app.m_Trials2] = f_Remove_art(app.m_Trials2,option,app.A1LEditField.Value,app.A2posfEditField.Value,app.A2posiEditField.Value);
            
           
            Stim_num = app.StimNumEditField.Value;
            Th = app.ThresholdEditField.Value;
            ti = app.InitialTimeEditField.Value;
            tf = app.FinalTimeEditField.Value;
            tis = app.InterStimTimeEditField.Value;
            all_trials = app.AllTrialsCheckBox.Value;
           
           
            v_Trials_str=app.SelectedTrialsEditField.Value;
            v_Trials=str2double(strsplit(v_Trials_str,','));
            
          
            [app.m_Trials1_w,t_end,app.m_Trials1_freq] = f_Assemble_Trials(app.m_Trials1,Stim_num,tis,app.srate);
            [app.m_Trials1_wt,app.v_Timew] = f_Assemble_Time(app.m_Trials1_w,app.v_ini,ti,tf,t_end,app.srate);
            [app.m_Trials1_selected,app.m_Trials1_freq_sel] = f_Select_Trials(app.m_Trials1_wt,v_Trials,app.v_Timew,all_trials,app.m_Trials2_freq,app.GF,app.h,Ch1);
        
             [app.m_Trials2_w,t_end,app.m_Trials2_freq] = f_Assemble_Trials(app.m_Trials2,Stim_num,tis,app.srate);
            [app.m_Trials2_wt,app.v_Timew] = f_Assemble_Time(app.m_Trials2_w,app.v_ini,ti,tf,t_end,app.srate);
            [app.m_Trials2_selected,app.m_Trials2_freq_sel] = f_Select_Trials(app.m_Trials2_wt,v_Trials,app.v_Timew,all_trials,app.m_Trials2_freq,app.GF,app.h,Ch2);
        
            
        end

        % Button pushed function: PACButton_2
        function PACButton_2Pushed(app, event)
            
            P1=app.P1EditField.Value;
            P2=app.P2EditField.Value;
            A1=app.A1EditField.Value;
            A2=app.A2EditField.Value;
            f_target=app.ResamplePAC.Value; 
            srt =round(app.srate/f_target);
            %f_Phase_PAC_Pdetect_inter(P1,P2,A1,A2,app.m_Trials1_selected, app.m_Trials2_selected,app.srate,srt);
            f_Phase_PAC_Pdetect_window(P1,P2,A1,A2,app.m_Trials1_selected, app.m_Trials2_selected,app.srate,srt);
        end

        % Button pushed function: HistButton
        function HistButtonPushed(app, event)
                    ps_MinFreqHz = app.F1.Value;
        ps_MaxFreqHz = app.F2.Value;
        ps_StDevCycles=app.CyclesEditField.Value;
        norm=app.NormCheckBox.Value;
        f_target=app.ResampleFr.Value; 
        srt =round(app.srate/f_target);

        f_tf_histogram(app.m_Trials1_selected,ps_MinFreqHz,ps_MaxFreqHz,srt,app.srate,ps_StDevCycles,norm,app.h,app.sel_Ch);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BasicInformationPanel = uipanel(app.UIFigure);
            app.BasicInformationPanel.Title = 'Basic Information';
            app.FileNameEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.FileNameEditField.Value = '17308005.abf';
            app.LoadButton = uibutton(app.BasicInformationPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.InitialFigureButton = uibutton(app.BasicInformationPanel, 'push');
            app.InitialFigureButton.ButtonPushedFcn = createCallbackFcn(app, @InitialFigureButtonPushed, true);
            app.InitialFigureButton.Text = 'Initial Figure';
            app.ChannelEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.ChannelEditField.Value = 1;
            app.StimNumEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.StimNumEditFieldLabel.Text = 'Stim Num';
            app.StimNumEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.StimNumEditField.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.ThresholdEditField.Value = 0.8;
            app.AllTrialsCheckBox = uicheckbox(app.BasicInformationPanel);
            app.AllTrialsCheckBox.Text = 'All Trials';
            app.AllTrialsCheckBox.Value = true;
            app.SelectedTrialsEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.SelectedTrialsEditFieldLabel.Text = 'Selected Trials';
            app.SelectedTrialsEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.SelectedTrialsEditField.Value = '[,2,5,10,14,21,30,]';
            app.InitialTimeEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.InitialTimeEditFieldLabel.Text = 'Initial Time';
            app.InitialTimeEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.InitialTimeEditField.Value = -0.05;
            app.FinalTimeEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.FinalTimeEditField.Value = Inf;
            app.LoadTrialsButton = uibutton(app.BasicInformationPanel, 'push');
            app.LoadTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrialsButtonPushed, true);
            app.LoadTrialsButton.Text = 'Load Trials';
            app.NoStimCheckBox = uicheckbox(app.BasicInformationPanel);
            app.NoStimCheckBox.Text = 'NoStim';
            app.InterStimTimeEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.InterStimTimeEditFieldLabel.Text = 'Inter Stim Time';
            app.InterStimTimeEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.InterStimTimeEditField.Value = 0.1;
            app.ResampleButton = uibutton(app.BasicInformationPanel, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.SampleFqHzEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.SampleFqHzEditFieldLabel.Text = 'Sample Fq Hz';
            app.SampleFqHzEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.SampleFqHzEditField.Value = 10000;
            app.SingleTrialEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.SingleTrialEditFieldLabel.Text = 'Single Trial';
            app.SingleTrialEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.SingleTrialEditField.Value = 1;
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.ERPPlotButton = uibutton(app.TimePanel, 'push');
            app.ERPPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ERPPlotButtonPushed, true);
            app.ERPPlotButton.Text = 'ERP Plot';
            app.RMSButton = uibutton(app.TimePanel, 'push');
            app.RMSButton.ButtonPushedFcn = createCallbackFcn(app, @RMSButtonPushed, true);
            app.RMSButton.Text = 'RMS';
            app.FqLowEditFieldLabel = uilabel(app.TimePanel);
            app.FqLowEditFieldLabel.Text = 'Fq Low';
            app.FqLowEditField = uieditfield(app.TimePanel, 'numeric');
            app.FqLowEditField.Value = 0.5;
            app.FqHighEditFieldLabel = uilabel(app.TimePanel);
            app.FqHighEditFieldLabel.Text = 'Fq High';
            app.FqHighEditField = uieditfield(app.TimePanel, 'numeric');
            app.FqHighEditField.Value = 4;
            app.OrderEditFieldLabel = uilabel(app.TimePanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.TimePanel, 'numeric');
            app.OrderEditField.Value = 8;
            app.filterButton = uibutton(app.TimePanel, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter';
            app.TrialviewButton = uibutton(app.TimePanel, 'push');
            app.TrialviewButton.ButtonPushedFcn = createCallbackFcn(app, @TrialviewButtonPushed, true);
            app.TrialviewButton.Text = 'Trial view';
            app.AverageButton = uibutton(app.TimePanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.FrequencyPanel = uipanel(app.UIFigure);
            app.FrequencyPanel.Title = 'Frequency';
            app.SampleFqHzLabel = uilabel(app.FrequencyPanel);
            app.SampleFqHzLabel.Text = 'Sample Fq Hz';
            app.ResampleFr = uieditfield(app.FrequencyPanel, 'numeric');
            app.ResampleFr.Value = 1000;
            app.FFTAverageButton = uibutton(app.FrequencyPanel, 'push');
            app.FFTAverageButton.ButtonPushedFcn = createCallbackFcn(app, @FFTAverageButtonPushed, true);
            app.FFTAverageButton.Text = 'FFT Average';
            app.PSDAverageButton = uibutton(app.FrequencyPanel, 'push');
            app.PSDAverageButton.ButtonPushedFcn = createCallbackFcn(app, @PSDAverageButtonPushed, true);
            app.PSDAverageButton.Text = 'PSD Average';
            app.PSDButton = uibutton(app.FrequencyPanel, 'push');
            app.PSDButton.ButtonPushedFcn = createCallbackFcn(app, @PSDButtonPushed, true);
            app.PSDButton.Text = 'PSD';
            app.FFTButton = uibutton(app.FrequencyPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.Fq1EditFieldLabel = uilabel(app.FrequencyPanel);
            app.Fq1EditFieldLabel.Text = 'Fq1';
            app.Fq1EditField = uieditfield(app.FrequencyPanel, 'numeric');
            app.Fq1EditField.Value = 1;
            app.Fq2EditFieldLabel = uilabel(app.FrequencyPanel);
            app.Fq2EditFieldLabel.Text = 'Fq2';
            app.Fq2EditField = uieditfield(app.FrequencyPanel, 'numeric');
            app.Fq2EditField.Value = 4;
            app.RelativePSDButton = uibutton(app.FrequencyPanel, 'push');
            app.RelativePSDButton.ButtonPushedFcn = createCallbackFcn(app, @RelativePSDButtonPushed, true);
            app.RelativePSDButton.Text = 'Relative PSD';
            app.PowEditFieldLabel = uilabel(app.FrequencyPanel);
            app.PowEditFieldLabel.Text = 'Pow';
            app.PowEditField = uieditfield(app.FrequencyPanel, 'numeric');
            app.PowEditField.Editable = 'off';
            app.RelativePowEditFieldLabel = uilabel(app.FrequencyPanel);
            app.RelativePowEditFieldLabel.Text = 'Relative Pow ';
            app.RelativePowEditField = uieditfield(app.FrequencyPanel, 'numeric');
            app.RelativePowEditField.Editable = 'off';
            app.MeasuresPanel = uipanel(app.UIFigure);
            app.MeasuresPanel.Title = 'Measures';
            app.PlotButton = uibutton(app.MeasuresPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DropDown = uidropdown(app.MeasuresPanel);
            app.DropDown.Items = {'x stim AV', 'Full AV', ''};
            app.DropDown.Value = 'x stim AV';
            app.R1t1EditFieldLabel = uilabel(app.MeasuresPanel);
            app.R1t1EditFieldLabel.Text = 'R1 t1';
            app.R1t1EditField = uieditfield(app.MeasuresPanel, 'numeric');
            app.R1t1EditField.Value = 0.007;
            app.R1t2EditFieldLabel = uilabel(app.MeasuresPanel);
            app.R1t2EditFieldLabel.Text = 'R1 t2';
            app.R1t2EditField = uieditfield(app.MeasuresPanel, 'numeric');
            app.R1t2EditField.Value = 0.01;
            app.R2t1EditFieldLabel = uilabel(app.MeasuresPanel);
            app.R2t1EditFieldLabel.Text = 'R2 t1';
            app.R2t1EditField = uieditfield(app.MeasuresPanel, 'numeric');
            app.R2t1EditField.Value = 0.107;
            app.R2t2EditFieldLabel = uilabel(app.MeasuresPanel);
            app.R2t2EditFieldLabel.Text = 'R2 t2';
            app.R2t2EditField = uieditfield(app.MeasuresPanel, 'numeric');
            app.R2t2EditField.Value = 0.11;
            app.MeasureButton = uibutton(app.MeasuresPanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.xEditFieldLabel = uilabel(app.MeasuresPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.MeasuresPanel, 'numeric');
            app.xEditField.Value = 6;
            app.TimeFrequencyPanel = uipanel(app.UIFigure);
            app.TimeFrequencyPanel.Title = 'Time-Frequency';
            app.F1EditField_2Label = uilabel(app.TimeFrequencyPanel);
            app.F1EditField_2Label.Text = 'F1';
            app.F1 = uieditfield(app.TimeFrequencyPanel, 'numeric');
            app.F1.Value = 1;
            app.F2EditField_2Label = uilabel(app.TimeFrequencyPanel);
            app.F2EditField_2Label.Text = 'F2';
            app.F2 = uieditfield(app.TimeFrequencyPanel, 'numeric');
            app.F2.Value = 500;
            app.CyclesEditFieldLabel = uilabel(app.TimeFrequencyPanel);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.TimeFrequencyPanel, 'numeric');
            app.CyclesEditField.Value = 2;
            app.NormCheckBox = uicheckbox(app.TimeFrequencyPanel);
            app.NormCheckBox.Text = 'Norm';
            app.SampleFqHzLabel_2 = uilabel(app.TimeFrequencyPanel);
            app.SampleFqHzLabel_2.Text = 'Sample Fq Hz';
            app.ResampleTF = uieditfield(app.TimeFrequencyPanel, 'numeric');
            app.ResampleTF.Value = 2000;
            app.LogCheckBox = uicheckbox(app.TimeFrequencyPanel);
            app.LogCheckBox.Text = 'Log';
            app.LogCheckBox.Value = true;
            app.TFButton = uibutton(app.TimeFrequencyPanel, 'push');
            app.TFButton.ButtonPushedFcn = createCallbackFcn(app, @TFButtonPushed, true);
            app.TFButton.Text = 'TF';
            app.TFAverageButton = uibutton(app.TimeFrequencyPanel, 'push');
            app.TFAverageButton.ButtonPushedFcn = createCallbackFcn(app, @TFAverageButtonPushed, true);
            app.TFAverageButton.Text = 'TF Average';
            app.HistButton = uibutton(app.TimeFrequencyPanel, 'push');
            app.HistButton.ButtonPushedFcn = createCallbackFcn(app, @HistButtonPushed, true);
            app.HistButton.Text = 'Hist';
            app.PACPanel = uipanel(app.UIFigure);
            app.PACPanel.Title = 'PAC';
            app.FqP1EditFieldLabel = uilabel(app.PACPanel);
            app.FqP1EditFieldLabel.Text = 'Fq P1';
            app.FqP1EditField = uieditfield(app.PACPanel, 'numeric');
            app.FqP1EditField.Value = 0.1;
            app.FqP2EditFieldLabel = uilabel(app.PACPanel);
            app.FqP2EditFieldLabel.Text = 'Fq P2';
            app.FqP2EditField = uieditfield(app.PACPanel, 'numeric');
            app.FqP2EditField.Value = 10;
            app.PstepEditFieldLabel = uilabel(app.PACPanel);
            app.PstepEditFieldLabel.Text = 'Pstep';
            app.PstepEditField = uieditfield(app.PACPanel, 'numeric');
            app.PstepEditField.Value = 0.1;
            app.FqA1EditFieldLabel = uilabel(app.PACPanel);
            app.FqA1EditFieldLabel.Text = 'Fq A1';
            app.FqA1EditField = uieditfield(app.PACPanel, 'numeric');
            app.FqA1EditField.Value = 10;
            app.FqA2EditFieldLabel = uilabel(app.PACPanel);
            app.FqA2EditFieldLabel.Text = 'Fq A2';
            app.FqA2EditField = uieditfield(app.PACPanel, 'numeric');
            app.FqA2EditField.Value = 500;
            app.AstepEditFieldLabel = uilabel(app.PACPanel);
            app.AstepEditFieldLabel.Text = 'Astep';
            app.AstepEditField = uieditfield(app.PACPanel, 'numeric');
            app.AstepEditField.Value = 0.5;
            app.MIButton = uibutton(app.PACPanel, 'push');
            app.MIButton.ButtonPushedFcn = createCallbackFcn(app, @MIButtonPushed, true);
            app.MIButton.Text = 'MI';
            app.SampleFqHzLabel_3 = uilabel(app.PACPanel);
            app.SampleFqHzLabel_3.Text = 'Sample Fq Hz';
            app.ResamplePAC = uieditfield(app.PACPanel, 'numeric');
            app.ResamplePAC.Value = 2000;
            app.PACButton = uibutton(app.PACPanel, 'push');
            app.PACButton.ButtonPushedFcn = createCallbackFcn(app, @PACButtonPushed, true);
            app.PACButton.Text = 'PAC';
            app.P1EditFieldLabel = uilabel(app.PACPanel);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.PACPanel, 'numeric');
            app.P1EditField.Value = 3;
            app.P2EditFieldLabel = uilabel(app.PACPanel);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.PACPanel, 'numeric');
            app.P2EditField.Value = 8;
            app.A1EditFieldLabel = uilabel(app.PACPanel);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.PACPanel, 'numeric');
            app.A1EditField.Value = 25;
            app.A2EditFieldLabel = uilabel(app.PACPanel);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.PACPanel, 'numeric');
            app.A2EditField.Value = 500;
            app.PACAverageButton = uibutton(app.PACPanel, 'push');
            app.PACAverageButton.ButtonPushedFcn = createCallbackFcn(app, @PACAverageButtonPushed, true);
            app.PACAverageButton.Text = 'PAC Average';
            app.PACPselButton = uibutton(app.PACPanel, 'push');
            app.PACPselButton.ButtonPushedFcn = createCallbackFcn(app, @PACPselButtonPushed, true);
            app.PACPselButton.Text = 'PAC Psel';
            app.ArtifactPanel = uipanel(app.UIFigure);
            app.ArtifactPanel.Title = 'Artifact';
            app.ViewButton = uibutton(app.ArtifactPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.RemoveDropDownLabel = uilabel(app.ArtifactPanel);
            app.RemoveDropDownLabel.Text = 'Remove';
            app.RemoveDropDown = uidropdown(app.ArtifactPanel);
            app.RemoveDropDown.Items = {'Off', 'On first', 'On both', ''};
            app.RemoveDropDown.Value = 'Off';
            app.A1LEditFieldLabel = uilabel(app.ArtifactPanel);
            app.A1LEditFieldLabel.Text = 'A1 L';
            app.A1LEditField = uieditfield(app.ArtifactPanel, 'numeric');
            app.A1LEditField.Value = 10;
            app.A2posiEditFieldLabel = uilabel(app.ArtifactPanel);
            app.A2posiEditFieldLabel.Text = 'A2 posi';
            app.A2posiEditField = uieditfield(app.ArtifactPanel, 'numeric');
            app.A2posiEditField.Value = 999;
            app.A2posfEditFieldLabel = uilabel(app.ArtifactPanel);
            app.A2posfEditFieldLabel.Text = 'A2 posf';
            app.A2posfEditField = uieditfield(app.ArtifactPanel, 'numeric');
            app.A2posfEditField.Value = 1004;
            app.ApplyButton = uibutton(app.ArtifactPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InterChannelPanel = uipanel(app.UIFigure);
            app.InterChannelPanel.Title = 'Inter Channel';
            app.PPCButton = uibutton(app.InterChannelPanel, 'push');
            app.PPCButton.ButtonPushedFcn = createCallbackFcn(app, @PPCButtonPushed, true);
            app.PPCButton.Text = 'PPC';
            app.PACButton_2 = uibutton(app.InterChannelPanel, 'push');
            app.PACButton_2.ButtonPushedFcn = createCallbackFcn(app, @PACButton_2Pushed, true);
            app.PACButton_2.Text = 'PAC';
            app.FminEditFieldLabel = uilabel(app.InterChannelPanel);
            app.FminEditFieldLabel.Text = 'F min';
            app.FminEditField = uieditfield(app.InterChannelPanel, 'numeric');
            app.FminEditField.Value = 0.1;
            app.FmaxEditFieldLabel = uilabel(app.InterChannelPanel);
            app.FmaxEditFieldLabel.Text = 'F max';
            app.FmaxEditField = uieditfield(app.InterChannelPanel, 'numeric');
            app.FmaxEditField.Value = 500;
            app.Ch_PEditFieldLabel = uilabel(app.InterChannelPanel);
            app.Ch_PEditFieldLabel.Text = 'Ch_P';
            app.Ch_PEditField = uieditfield(app.InterChannelPanel, 'numeric');
            app.Ch_PEditField.Value = 1;
            app.Ch_AEditFieldLabel = uilabel(app.InterChannelPanel);
            app.Ch_AEditFieldLabel.Text = 'Ch_A';
            app.Ch_AEditField = uieditfield(app.InterChannelPanel, 'numeric');
            app.Ch_AEditField.Value = 2;
            app.FqHzLabel = uilabel(app.InterChannelPanel);
            app.FqHzLabel.Text = 'Fq Hz';
            app.Resample_inter_Ch = uieditfield(app.InterChannelPanel, 'numeric');
            app.Resample_inter_Ch.Value = 2000;
            app.wdEditFieldLabel = uilabel(app.InterChannelPanel);
            app.wdEditFieldLabel.Text = 'wd';
            app.wdEditField = uieditfield(app.InterChannelPanel, 'numeric');
            app.wdEditField.Value = 0.05;
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = {'Load'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BOARD_FTD_PACC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end